classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArbitraryFunctionGenerator  matlab.ui.Figure
        AmplitudeKnobLabel          matlab.ui.control.Label
        AmplitudeKnob               matlab.ui.control.Knob
        UIAxes                      matlab.ui.control.UIAxes
        WaveformTypeKnobLabel       matlab.ui.control.Label
        WaveformTypeKnob            matlab.ui.control.DiscreteKnob
        UIAxes2                     matlab.ui.control.UIAxes
        FrequencyKnobLabel          matlab.ui.control.Label
        FrequencyKnob               matlab.ui.control.Knob
        FrequencyEditField          matlab.ui.control.NumericEditField
        PeriodKnobLabel             matlab.ui.control.Label
        PeriodKnob                  matlab.ui.control.Knob
        PeriodEditField             matlab.ui.control.NumericEditField
        AngularFrequencyKnobLabel   matlab.ui.control.Label
        AngularFrequencyKnob        matlab.ui.control.Knob
        AngularFreqEditField        matlab.ui.control.NumericEditField
        AmplitudeEditField          matlab.ui.control.NumericEditField
        DCOffsetEditFieldLabel      matlab.ui.control.Label
        DCOffsetEditField           matlab.ui.control.NumericEditField
        ResetButton                 matlab.ui.control.Button
        SoundSwitchLabel            matlab.ui.control.Label
        SoundSwitch                 matlab.ui.control.ToggleSwitch
        AutoPlotSwitchLabel         matlab.ui.control.Label
        AutoPlotSwitch              matlab.ui.control.Switch
        CustomFunctionsPanel        matlab.ui.container.Panel
        CustomFunctionInstructionsLabel  matlab.ui.control.Label
        ftLabel                     matlab.ui.control.Label
        CustomFunctionEditField     matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        SeriesVersionsSwitch        matlab.ui.control.ToggleSwitch
        FourierBasedLampLabel       matlab.ui.control.Label
        FourierBasedLamp            matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        wave % the class for calculation and individual wave storage
    end
    
    properties (Access = private)
        autoplot % bool for autoplot
        soundBool % bool for sound playback
        customFunction = "" %intermediary holder for custom user fuctions
    end
    
    methods (Access = private)
        
        function plotApp(app, inp)
            if nargin == 2
                if inp == 1 %force a plot through disabled autoplot
                    app.wave.plotfApp(app.UIAxes);
                    app.wave.plotfApp(app.UIAxes2);
                end
            else
                if app.autoplot == 1
                    if isa(app.wave.type, 'cell') == 1
                        app.wave.seriesplot(app.UIAxes, 0, 16*pi, 50);
                        app.wave.seriesplot(app.UIAxes2, 0, 16*pi, 50);
                    else
                        app.wave.plotfApp(app.UIAxes);
                        app.wave.plotfApp(app.UIAxes2);
                    end
                end
            end
        end
        
        
        function refreshFunction(app)
            if app.customFunction ~= ""
                charfunc = app.customFunction;
                charfunc = strrep(charfunc, 'f', num2str(app.wave.f));
                charfunc = strrep(charfunc, 'A', num2str(app.wave.A));
                charfunc = strrep(charfunc, 'W', num2str(app.wave.omega));
                charfunc = strrep(charfunc, 'T', num2str(app.wave.T));
                charfunc = strrep(charfunc, 'DC', num2str(app.wave.DCoffset));
                charfunc = lower(charfunc);
                try %prevents user code that fails from stalling the program
                    custFunc = str2func(['@(t)', charfunc]);
                    app.wave.setFunction(custFunc);
                catch
                    app.Label.Text = 'This is not a valid function.';
                    warning('Warning: Invalid function input by user.');
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.wave = waveFunction();
            app.soundBool = 0;
            app.autoplot = 1;
            pause(3);
            plotApp(app, 1);
        end

        % Value changed function: WaveformTypeKnob
        function WaveformTypeKnobValueChanged(app, event)
            value = app.WaveformTypeKnob.Value;
            app.wave.setFunction(value);
            
            plotApp(app);
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            A = app.AmplitudeKnob.Value;
            app.AmplitudeEditField.Value = A;
            app.wave.setAmp(A);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            freq = app.FrequencyKnob.Value;
            omega = 2*pi*freq;
            T = 1/freq;
            app.FrequencyEditField.Value = freq;
            app.AngularFreqEditField.Value = omega;
            app.PeriodEditField.Value = T;
            app.wave.setFreq(freq);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            freq = app.FrequencyEditField.Value;
            omega = 2*pi*freq;
            T = 1/freq;
            app.AngularFreqEditField.Value = omega;
            app.PeriodEditField.Value = T;
            app.wave.setFreq(freq);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: PeriodKnob
        function PeriodKnobValueChanged(app, event)
            T = app.PeriodKnob.Value;
            freq = 1/T;
            omega = 2*pi*freq;
            app.AngularFreqEditField.Value = omega;
            app.PeriodEditField.Value = T;
            app.FrequencyEditField.Value = freq;
            app.wave.setPeriod(T);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: PeriodEditField
        function PeriodEditFieldValueChanged(app, event)
            T = app.PeriodEditField.Value;
            freq = 1/T;
            omega = 2*pi*freq;
            app.AngularFreqEditField.Value = omega;
            app.FrequencyEditField.Value = freq;
            app.wave.setPeriod(T);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: AngularFreqEditField
        function AngularFreqEditFieldValueChanged(app, event)
            omega = app.AngularFreqEditField.Value;
            freq = omega/(2*pi);
            T = 1/freq;
            app.PeriodEditField.Value = T;
            app.FrequencyEditField.Value = freq;
            app.wave.setAngularFreq(omega);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: AngularFrequencyKnob
        function AngularFrequencyKnobValueChanged(app, event)
            omega = app.AngularFrequencyKnob.Value;
            freq = omega/(2*pi);
            T = 1/freq;
            app.AngularFreqEditField.Value = omega;
            app.PeriodEditField.Value = T;
            app.FrequencyEditField.Value = freq;
            app.wave.setAngularFreq(omega);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            A = app.AmplitudeEditField.Value;
            app.wave.setAmp(A);
            plotApp(app);
        end

        % Value changed function: DCOffsetEditField
        function DCOffsetEditFieldValueChanged(app, event)
            DCoffset = app.DCOffsetEditField.Value;
            app.wave.setDCoffset(DCoffset);
            
            refreshFunction(app);
            plotApp(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.wave = waveFunction();
            app.WaveformTypeKnob.Value = '1';
            app.AmplitudeKnob.Value = 1;
            app.AmplitudeEditField.Value = 1;
            app.FrequencyKnob.Value = 0.159154943091895;
            app.FrequencyEditField.Value = 0.159154943091895;
            app.PeriodKnob.Value = 6.28318530717959;
            app.PeriodEditField.Value = 6.28318530717959;
            app.AngularFrequencyKnob.Value = 1;
            app.AngularFreqEditField.Value = 1;
            app.DCOffsetEditField.Value = 0;
            app.CustomFunctionEditField.Value = '';
            
            
            app.UIAxes.XLim = [0 12.5663706143592];
            app.UIAxes.YLim = [-16 16];
            app.UIAxes2.XLim = [0 50.2654824574367];
            app.UIAxes2.YLim = [-1 1];
            
            plotApp(app);
        end

        % Value changed function: AutoPlotSwitch
        function AutoPlotSwitchValueChanged(app, event)
            value = app.AutoPlotSwitch.Value;
            stringVal = convertCharsToStrings(value);
            if strcmp(stringVal,"On")==1
                app.autoplot = 1;
            else
                app.autoplot = 0;
            end
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: SoundSwitch
        function SoundSwitchValueChanged(app, event)
            value = app.SoundSwitch.Value;
            stringVal = convertCharsToStrings(value);
            app.soundBool = strcmp(stringVal,"On");
            while app.soundBool==1
                app.wave.playSound(1);
                pause(1);
            end
        end

        % Value changed function: CustomFunctionEditField
        function CustomFunctionEditFieldValueChanged(app, event)
            charfunc = app.CustomFunctionEditField.Value;
            charfunc = strrep(charfunc, '^', '.^');
            charfunc = strrep(charfunc, '*', '.*');
            charfunc = strrep(charfunc, '/', './'); %vectorizing operators
            app.customFunction = charfunc; % storing vectorized function
            app.Label.Text = ''; %removes any error text
            
            refreshFunction(app);
            plotApp(app);
        end

        % Value changed function: SeriesVersionsSwitch
        function SeriesVersionsSwitchValueChanged(app, event)
            value = app.SeriesVersionsSwitch.Value;
            value = str2double(value);
            if value == 0
                app.FourierBasedLamp.Color = [1,0,0];
                app.WaveformTypeKnob.ItemsData = {'1', '2', '3', '4', '5'};
            else
                app.FourierBasedLamp.Color = [0,1,0];
                app.WaveformTypeKnob.ItemsData = {'1', '7', '8', '9', '10'};
            end
            refreshFunction(app);
            plotApp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArbitraryFunctionGenerator = uifigure;
            app.ArbitraryFunctionGenerator.Name = 'UI Figure';
            app.AmplitudeKnobLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.ArbitraryFunctionGenerator, 'continuous');
            app.AmplitudeKnob.Limits = [0 12];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.AmplitudeKnob.Value = 1;
            app.UIAxes = uiaxes(app.ArbitraryFunctionGenerator);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            app.UIAxes.XLim = [0 12.5663706143592];
            app.UIAxes.YLim = [-16 16];
            app.WaveformTypeKnobLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.WaveformTypeKnobLabel.Text = 'Waveform Type';
            app.WaveformTypeKnob = uiknob(app.ArbitraryFunctionGenerator, 'discrete');
            app.WaveformTypeKnob.Items = {'Sin', 'Rectangular', 'Triangular', 'Sawtooth', 'Full-rectified'};
            app.WaveformTypeKnob.ItemsData = {'1', '7', '8', '9', '10'};
            app.WaveformTypeKnob.ValueChangedFcn = createCallbackFcn(app, @WaveformTypeKnobValueChanged, true);
            app.WaveformTypeKnob.Value = '1';
            app.UIAxes2 = uiaxes(app.ArbitraryFunctionGenerator);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'f(t)')
            app.FrequencyKnobLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.ArbitraryFunctionGenerator, 'continuous');
            app.FrequencyKnob.Limits = [0 20000];
            app.FrequencyKnob.MajorTickLabels = {'0', '4000', '8000', '12000', '16000', '20000'};
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.FrequencyEditField = uieditfield(app.ArbitraryFunctionGenerator, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 0.159154943091895;
            app.PeriodKnobLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.PeriodKnobLabel.Text = 'Period';
            app.PeriodKnob = uiknob(app.ArbitraryFunctionGenerator, 'continuous');
            app.PeriodKnob.ValueChangedFcn = createCallbackFcn(app, @PeriodKnobValueChanged, true);
            app.PeriodEditField = uieditfield(app.ArbitraryFunctionGenerator, 'numeric');
            app.PeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodEditFieldValueChanged, true);
            app.PeriodEditField.Value = 6.28318530717959;
            app.AngularFrequencyKnobLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.AngularFrequencyKnobLabel.Text = 'Angular Frequency';
            app.AngularFrequencyKnob = uiknob(app.ArbitraryFunctionGenerator, 'continuous');
            app.AngularFrequencyKnob.Limits = [0 40];
            app.AngularFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @AngularFrequencyKnobValueChanged, true);
            app.AngularFrequencyKnob.Value = 1;
            app.AngularFreqEditField = uieditfield(app.ArbitraryFunctionGenerator, 'numeric');
            app.AngularFreqEditField.ValueChangedFcn = createCallbackFcn(app, @AngularFreqEditFieldValueChanged, true);
            app.AngularFreqEditField.Value = 1;
            app.AmplitudeEditField = uieditfield(app.ArbitraryFunctionGenerator, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 1;
            app.DCOffsetEditFieldLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.DCOffsetEditFieldLabel.Text = 'DC Offset';
            app.DCOffsetEditField = uieditfield(app.ArbitraryFunctionGenerator, 'numeric');
            app.DCOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @DCOffsetEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.ArbitraryFunctionGenerator, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SoundSwitchLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.SoundSwitchLabel.Text = 'Sound';
            app.SoundSwitch = uiswitch(app.ArbitraryFunctionGenerator, 'toggle');
            app.SoundSwitch.ValueChangedFcn = createCallbackFcn(app, @SoundSwitchValueChanged, true);
            app.AutoPlotSwitchLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.AutoPlotSwitchLabel.Text = 'Auto Plot';
            app.AutoPlotSwitch = uiswitch(app.ArbitraryFunctionGenerator, 'slider');
            app.AutoPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoPlotSwitchValueChanged, true);
            app.AutoPlotSwitch.Value = 'On';
            app.CustomFunctionsPanel = uipanel(app.ArbitraryFunctionGenerator);
            app.CustomFunctionsPanel.Title = 'Custom Functions';
            app.CustomFunctionInstructionsLabel = uilabel(app.CustomFunctionsPanel);
            app.CustomFunctionInstructionsLabel.Text = {'Custom Function Instructions: '; 'Write your own function to be displayed using dynamic values provided by'; 'the knobs. It doesn''t even have to be a waveform! All inbuilt Matlab math'; 'functions not involving symbolic variables should work.'; ''; 'Varibles Available:'; 'Frequency: f, Angular Frequency: W, Amplitude: A, Period: T,'; 'DC Offset: DC, Dependant Variable: t'};
            app.ftLabel = uilabel(app.CustomFunctionsPanel);
            app.ftLabel.Text = 'f(t)  = ';
            app.CustomFunctionEditField = uieditfield(app.CustomFunctionsPanel, 'text');
            app.CustomFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @CustomFunctionEditFieldValueChanged, true);
            app.CustomFunctionEditField.Tooltip = {'Custom Function Goes Here'};
            app.Label = uilabel(app.CustomFunctionsPanel);
            app.Label.Text = '';
            app.SeriesVersionsSwitch = uiswitch(app.ArbitraryFunctionGenerator, 'toggle');
            app.SeriesVersionsSwitch.ItemsData = {'0', '1'};
            app.SeriesVersionsSwitch.ValueChangedFcn = createCallbackFcn(app, @SeriesVersionsSwitchValueChanged, true);
            app.SeriesVersionsSwitch.Value = '1';
            app.FourierBasedLampLabel = uilabel(app.ArbitraryFunctionGenerator);
            app.FourierBasedLampLabel.Text = 'Fourier-Based';
            app.FourierBasedLamp = uilamp(app.ArbitraryFunctionGenerator);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArbitraryFunctionGenerator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArbitraryFunctionGenerator)
        end
    end
end